classdef TRPL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        ConvPadnsEditField           matlab.ui.control.NumericEditField
        ConvPadnsEditFieldLabel      matlab.ui.control.Label
        WindowBaselineEndindexEditField  matlab.ui.control.NumericEditField
        WindowBaselineEndindexEditFieldLabel  matlab.ui.control.Label
        WindowBaselineStartindexEditField  matlab.ui.control.NumericEditField
        WindowBaselineStartindexEditFieldLabel  matlab.ui.control.Label
        WindowUpperBoundnsEditField  matlab.ui.control.NumericEditField
        WindowUpperBoundnsEditFieldLabel  matlab.ui.control.Label
        WindowLowerBoundnsEditField  matlab.ui.control.NumericEditField
        WindowLowerBoundnsEditFieldLabel  matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        BuildModelButton             matlab.ui.control.Button
        FitButton                    matlab.ui.control.Button
        ExportDataasmatButton        matlab.ui.control.Button
        ExportTRPLDecayasFigButton   matlab.ui.control.Button
        ExportResidualsasFigButton   matlab.ui.control.Button
        SetBaselineBoundsButton      matlab.ui.control.StateButton
        SetWindowBoundsButton        matlab.ui.control.StateButton
        LoadDecayDataButton          matlab.ui.control.Button
        ResetClearButton             matlab.ui.control.Button
        LoadIRFDataButton            matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        RawTime                   double = []    % Time axis extracted from the IRF file (in nanoseconds)
        RawIRF                    double = []    % Instrument Response Function values
        RawData                   double = []    % TRPL decay measurement data
        ConvPadValue              double = 15    % Padding length used during convolution-based fitting
        WindowLBValue             double = 48    % Lower index bound for selecting the fit window
        WindowUBValue             double = 80    % Upper index bound for selecting the fit window
        WindowBaselineStartValue  double = 900   % Starting index of baseline range
        WindowBaselineEndValue    double = 1100  % Ending index of baseline range
        FitResults                struct = struct()  % Stores the latest fit output (parameters, RMSE, etc.)
        IRFTimeShift              double = 0      % Time shift value applied to the IRF for alignment
        NumExpComponents          double = 0      % Number of exponential terms in the model
        NumPowerComponents        double = 0      % Number of power-law terms in the model
        NumSecondComponents       double = 0      % Number of second-order kinetic terms
        ModelName                 char = ''       % Descriptive name for the current model configuration
        ParameterTable            cell = {}       % Table data containing fit parameters and bounds
        FitType                                   % fittype object used for curve fitting
        FitOptions                                % fitoptions object defining fit constraints
        FitModel                 char = ''        % Encoded string representing model type (e.g. "1exp1power")
        FitRangeClicks           double = []      % Raw click values for selecting fit region
        FitRangePatch            matlab.graphics.primitive.Patch = matlab.graphics.primitive.Patch.empty;  % Patch object for visualizing fit range
        FitRangeMarkers          matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty;  % Line markers for fit range
        BaselineRangeClicks      double = [];     % Raw click values for selecting baseline region
        BaselineRangePatch       matlab.graphics.primitive.Patch = matlab.graphics.primitive.Patch.empty;  % Patch object for visualizing baseline range
        BaselineRangeMarkers     matlab.graphics.chart.primitive.Line = matlab.graphics.chart.primitive.Line.empty;  % Line markers for baseline range
    end

    properties (Access = public)
        FitLowerSlider          matlab.ui.control.Slider  % Slider to set lower bound of fit window
        FitUpperSlider          matlab.ui.control.Slider  % Slider to set upper bound of fit window
        BaselineStartSlider     matlab.ui.control.Slider  % Slider to select baseline start time
        BaselineEndSlider       matlab.ui.control.Slider  % Slider to select baseline end time
        IRFTimeShiftEditField          matlab.ui.control.NumericEditField  % Numeric input for adjusting IRF time shift
        IRFTimeShiftEditFieldLabel     matlab.ui.control.Label             % Label for IRF time shift input
    end

    methods (Access = private)

        function startupFcn(app)
            % Maximize the app window when it first launches
            app.UIFigure.WindowState = 'maximized';
        end

        function IRFTimeShiftEditFieldValueChanged(app, event)
            % Update the stored IRF time shift when the input field changes
            app.IRFTimeShift = app.IRFTimeShiftEditField.Value;
        end

        function isValid = validateParameterTable(app)
            % Validate all entries in the parameter table for correct structure and logic
            isValid = true;
            tableData = app.UITable.Data;

            if isempty(tableData)
                uialert(app.UIFigure, 'Parameter table is empty. Build a model first.', 'Validation Error');
                isValid = false;
                return;
            end

            for i = 1:size(tableData, 1)
                paramName = tableData{i, 1};
                initGuess = tableData{i, 2};
                lowerBound = tableData{i, 3};
                upperBound = tableData{i, 4};

                % Ensure numerical and finite initial guess
                if ~isnumeric(initGuess) || ~isfinite(initGuess)
                    uialert(app.UIFigure, sprintf('Invalid initial guess for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Ensure lower bound is numeric and finite
                if ~isnumeric(lowerBound) || ~isfinite(lowerBound)
                    uialert(app.UIFigure, sprintf('Invalid lower bound for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Ensure upper bound is valid (finite or Inf)
                if ~isnumeric(upperBound) || (~isfinite(upperBound) && ~isinf(upperBound))
                    uialert(app.UIFigure, sprintf('Invalid upper bound for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Validate that lower bound is strictly less than upper bound
                if lowerBound >= upperBound && isfinite(upperBound)
                    uialert(app.UIFigure, sprintf('Lower bound must be less than upper bound for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Ensure initial guess falls within specified bounds
                if initGuess < lowerBound || (isfinite(upperBound) && initGuess > upperBound)
                    uialert(app.UIFigure, sprintf('Initial guess must be between bounds for %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end

                % Warn if amplitude is negative
                if contains(paramName, 'Amplitude') && initGuess < 0
                    uialert(app.UIFigure, sprintf('Amplitude parameters should be positive: %s', paramName), 'Validation Warning');
                end

                % Lifetime parameters must be strictly positive
                if contains(paramName, 'Lifetime') && initGuess <= 0
                    uialert(app.UIFigure, sprintf('Lifetime parameters should be positive: %s', paramName), 'Validation Error');
                    isValid = false;
                    return;
                end
            end
        end

        function buildParameterTable(app)
            % Dynamically construct the parameter table based on selected model components and data
            paramNames = {};
            initGuess = [];
            lowerBounds = [];
            upperBounds = [];

            if ~isempty(app.RawData)
                dataLength = length(app.RawData);
                startIdx = min(app.WindowBaselineStartValue, dataLength);
                endIdx = min(app.WindowBaselineEndValue, dataLength);

                if startIdx >= endIdx || startIdx < 1
                    startIdx = max(1, round(dataLength * 0.9));
                    endIdx = dataLength;
                end

                baseline = mean(app.RawData(startIdx:endIdx));
                maxData = max(app.RawData);
                maxTime = max(app.RawTime);
            else
                baseline = 10;
                maxData = 1000;
                maxTime = 100;
            end

            % Add baseline offset parameter
            paramNames{end+1} = 'Y Offset (Baseline)';
            initGuess(end+1) = baseline;
            lowerBounds(end+1) = 0;
            upperBounds(end+1) = 5 * baseline;

            % Add exponential components (amplitude and lifetime)
            for i = 1:app.NumExpComponents
                paramNames{end+1} = sprintf('Exp%d Amplitude', i);
                initGuess(end+1) = maxData / (2^i);
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Exp%d Lifetime', i);
                initGuess(end+1) = i == 1 && 5 || 50;
                lowerBounds(end+1) = 0.2;
                upperBounds(end+1) = isempty(app.RawTime) && 200 || maxTime * 2;
            end

            % Add power law components (amplitude, onset, alpha)
            for i = 1:app.NumPowerComponents
                paramNames{end+1} = sprintf('Power%d Amplitude', i);
                initGuess(end+1) = maxData / 2;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Power%d Onset', i);
                initGuess(end+1) = 0.1;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Power%d Alpha', i);
                initGuess(end+1) = 1.5;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = 5;
            end

            % Add second-order kinetic components (amplitude, kinetic)
            for i = 1:app.NumSecondComponents
                paramNames{end+1} = sprintf('Second%d Amplitude', i);
                initGuess(end+1) = maxData;
                lowerBounds(end+1) = 0;
                upperBounds(end+1) = Inf;

                paramNames{end+1} = sprintf('Second%d Kinetic', i);
                initGuess(end+1) = 0.1;
                lowerBounds(end+1) = 0.001;
                upperBounds(end+1) = Inf;
            end

            % Populate and update the parameter table
            numParams = length(paramNames);
            tableData = cell(numParams, 4);
            for i = 1:numParams
                tableData{i,1} = paramNames{i};
                tableData{i,2} = initGuess(i);
                tableData{i,3} = lowerBounds(i);
                tableData{i,4} = upperBounds(i);
            end

            app.UITable.ColumnName = {'Parameter', 'Initial Guess', 'Lower Bound', 'Upper Bound'};
            app.UITable.Data = tableData;
            app.UITable.ColumnEditable = [false, true, true, true];
            app.ParameterTable = tableData;
        end


    end

    methods (Access = public)

        function drawFitRangePatch(app, bounds)
            % Render a shaded region on the plot representing the fitting window
            if ~isempty(app.FitRangePatch) && isvalid(app.FitRangePatch)
                delete(app.FitRangePatch);
            end
            yl = app.UIAxes.YLim;
            app.FitRangePatch = patch(app.UIAxes, ...
                [bounds(1) bounds(2) bounds(2) bounds(1)], ...
                [yl(1) yl(1) yl(2) yl(2)], ...
                [0.3 0.3 0.7], 'FaceAlpha', 0.5, 'EdgeColor', 'none');
            uistack(app.FitRangePatch, 'bottom');
        end

        function drawBaselineRangePatch(app, bounds)
            % Render a shaded region on the plot representing the baseline region
            if ~isempty(app.BaselineRangePatch) && isvalid(app.BaselineRangePatch)
                delete(app.BaselineRangePatch);
            end
            yl = app.UIAxes.YLim;
            app.BaselineRangePatch = patch(app.UIAxes, ...
                [bounds(1) bounds(2) bounds(2) bounds(1)], ...
                [yl(1) yl(1) yl(2) yl(2)], ...
                [0.7 0.3 0.3], 'FaceAlpha', 0.3, 'EdgeColor', 'none');
            uistack(app.BaselineRangePatch, 'bottom');
        end

        function updateWindowLB(app, src, ~)
            % Smoothly update fit‑window lower bound as the left drawline moves
            x = src.Position(1,1);
            app.WindowLowerBoundnsEditField.Value = x;
            app.WindowLBValue = x;

            % If the shaded patch already exists, update its vertices in place
            if ~isempty(app.FitRangePatch) && isvalid(app.FitRangePatch)
                hHigh = findobj(app.UIAxes, 'Tag', 'windowLineHigh');
                ub = hHigh.Position(1,1);
                yl = app.UIAxes.YLim;
                set(app.FitRangePatch, ...
                    'XData', [x ub ub x], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end
        end

        function updateWindowUB(app, src, ~)
            % Smoothly update fit‑window upper bound as the right drawline moves
            x = src.Position(1,1);
            app.WindowUpperBoundnsEditField.Value = x;
            app.WindowUBValue = x;

            if ~isempty(app.FitRangePatch) && isvalid(app.FitRangePatch)
                hLow = findobj(app.UIAxes, 'Tag', 'windowLineLow');
                lb = hLow.Position(1,1);
                yl = app.UIAxes.YLim;
                set(app.FitRangePatch, ...
                    'XData', [lb x x lb], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end
        end

        function updateBaselineStart(app, src, ~)
            % Respond to dragging of the baseline start marker and update index
            % Smoothly update baseline start index & shaded region when dragging left baseline line
            x = src.Position(1,1);
            [~, idx] = min(abs(app.RawTime - x));
            app.WindowBaselineStartindexEditField.Value = idx;
            app.WindowBaselineStartValue = idx;

            if ~isempty(app.BaselineRangePatch) && isvalid(app.BaselineRangePatch)
                hHigh = findobj(app.UIAxes, 'Tag', 'baselineLineHigh');
                xEnd = hHigh.Position(1,1);
                yl = app.UIAxes.YLim;
                set(app.BaselineRangePatch, ...
                    'XData', [x xEnd xEnd x], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end
        end

        function updateBaselineEnd(app, src, ~)
            % Smoothly update baseline end index & shaded region when dragging right baseline line
            x = src.Position(1,1);
            [~, idx] = min(abs(app.RawTime - x));
            app.WindowBaselineEndindexEditField.Value = idx;
            app.WindowBaselineEndValue = idx;

            if ~isempty(app.BaselineRangePatch) && isvalid(app.BaselineRangePatch)
                hLow = findobj(app.UIAxes, 'Tag', 'baselineLineLow');
                xStart = hLow.Position(1,1);
                yl = app.UIAxes.YLim;
                set(app.BaselineRangePatch, ...
                    'XData', [xStart x x xStart], ...
                    'YData', [yl(1) yl(1) yl(2) yl(2)]);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadIRFDataButton
        function LoadIRFDataButtonPushed(app, event)
           % Open file selection dialog for user to choose an IRF Excel file
[irf_file, irf_path] = uigetfile( ...
    {'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'}, ...
    'Select IRF Excel File');
if isequal(irf_file,0)
    uialert(app.UIFigure,'IRF file selection canceled.','File Error');
    return
end

try
    % Construct full file path from selected name and path
    fname = fullfile(irf_path, irf_file);

    % Read the entire Excel sheet as raw cell array for header inspection
    rawCells = readcell(fname);

    % Locate the first row where any cell contains a ':' (assumed header row marker)
    hasColon = cellfun(@(x) ischar(x) && contains(x, ':'), rawCells);
    headerRow = find(any(hasColon,2), 1, 'first');
    if isempty(headerRow)
        error('Could not find any row containing '':'' to use as header.');
    end

    % Read the table starting from the identified header row
    tblIRF = readtable(fname, ...
        'PreserveVariableNames', true, ...
        'ReadVariableNames',    true, ...
        'HeaderLines',          headerRow-1);

    % Prompt user to select which column corresponds to time
    columnNames = tblIRF.Properties.VariableNames;
    [timeIdx, ok1] = listdlg( ...
        'PromptString','Select Time column:', ...
        'ListString', columnNames, ...
        'SelectionMode','single',    ...
        'ListSize',[300,200]);
    if ~ok1, return; end

    % Prompt user to select which column corresponds to IRF signal
    [irfIdx, ok2] = listdlg( ...
        'PromptString','Select IRF column:', ...
        'ListString', columnNames, ...
        'SelectionMode','single',    ...
        'ListSize',[300,200]);
    if ~ok2, return; end

    % Store selected columns as RawTime and RawIRF in app state
    app.RawTime = tblIRF{:,timeIdx}(:)';
    app.RawIRF  = tblIRF{:,irfIdx}(:)';

    % Clear previous plot and display the newly loaded IRF data
    cla(app.UIAxes);
    plot(app.UIAxes, app.RawTime, app.RawIRF);
    title(app.UIAxes, 'Instrument Response (IRF)');
    xlabel(app.UIAxes, 'Time (ns)');
    ylabel(app.UIAxes, 'IRF Amplitude');

    % Notify user that IRF data was successfully loaded
    uialert(app.UIFigure,'IRF data loaded successfully!','Success');
catch ME
    % Display error message if loading or processing fails
    uialert(app.UIFigure, ME.message, 'Load Error');
end

        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
           % Gray out the Fit button immediately
        app.FitButton.Enable = 'off';
        drawnow;

        % 1) Require IRF & decay data
        if isempty(app.RawTime) || isempty(app.RawIRF) || isempty(app.RawData)
            uialert(app.UIFigure,'Load IRF and decay first.','Error');
            app.FitButton.Enable = 'on';
            return
        end

        % 2) Require a built model
        if isempty(app.ModelName) || isempty(app.ParameterTable)
            uialert(app.UIFigure,'Build a model first using "Build Model" button.','Error');
            app.FitButton.Enable = 'on';
            return
        end

        % 3) Validate the parameter table
        if ~app.validateParameterTable()
            app.FitButton.Enable = 'on';
            return
        end

        % Retrieve inputs and UI values
        RawTime = app.RawTime;
        RawIRF  = app.RawIRF;
        RawData = app.RawData;
        ConvPad = app.ConvPadnsEditField.Value;

        % Determine fit-window bounds
        lb = app.WindowLowerBoundnsEditField.Value;
        if isempty(lb) || lb <= 0
            WindowLB = app.WindowLBValue;
        else
            WindowLB = lb;
        end
        ub = app.WindowUpperBoundnsEditField.Value;
        if isempty(ub) || ub <= 0
            WindowUB = app.WindowUBValue;
        else
            WindowUB = ub;
        end

        % Determine baseline range 
        sb = app.WindowBaselineStartindexEditField.Value;
        eb = app.WindowBaselineEndindexEditField.Value;
        dataLength = numel(RawData);
        if isempty(sb) || isempty(eb) || sb <= 0 || eb <= 0 || sb > dataLength || eb > dataLength
            startIdx = max(1, round(dataLength * 0.9));
            endIdx   = dataLength;
            app.WindowBaselineStartindexEditField.Value = startIdx;
            app.WindowBaselineEndindexEditField.Value   = endIdx;
            app.WindowBaselineStartValue = startIdx;
            app.WindowBaselineEndValue   = endIdx;
        else
            startIdx = max(1, min(sb, dataLength));
            endIdx   = min(eb, dataLength);
            if startIdx >= endIdx
                startIdx = max(1, endIdx - 10);
            end
        end
        WindowBaseline = startIdx:endIdx;

        % Extract table-based guesses & bounds
        tableData   = app.UITable.Data;
        nParams     = size(tableData,1);
        InitialGuess = zeros(1,nParams+1);
        LowerBound   = zeros(1,nParams+1);
        UpperBound   = zeros(1,nParams+1);
        for i = 1:nParams
            InitialGuess(i) = tableData{i,2};
            LowerBound(i)   = tableData{i,3};
            UpperBound(i)   = tableData{i,4};
        end

        % --- CORRECTED LINE: use the app.IRFTimeShift property ---
        InitialGuess(end) = app.IRFTimeShift;
        LowerBound(end)   = -3;
        UpperBound(end)   = 3;

        % Gather model info
        FitModel           = app.ModelName;
        NumExpComponents   = app.NumExpComponents;
        NumPowerComponents = app.NumPowerComponents;
        NumSecondComponents= app.NumSecondComponents;

        % Ensure your fitting script is on the path
        scriptDir = fileparts(mfilename('fullpath'));
        if ~contains(path, scriptDir)
            addpath(scriptDir);
        end

        % Pack everything into workspace
        tempVars = struct( ...
            'RawTime',RawTime, ...
            'RawIRF',RawIRF, ...
            'RawData',RawData, ...
            'ConvPad',ConvPad, ...
            'WindowLB',WindowLB, ...
            'WindowUB',WindowUB, ...
            'WindowBaseline',WindowBaseline, ...
            'FitModel',FitModel, ...
            'NumExpComponents',NumExpComponents, ...
            'NumPowerComponents',NumPowerComponents, ...
            'NumSecondComponents',NumSecondComponents, ...
            'InitialGuess',InitialGuess, ...
            'LowerBound',LowerBound, ...
            'UpperBound',UpperBound ...
        );
        varNames = fieldnames(tempVars);
        oldVars  = struct();
        for k = 1:numel(varNames)
            v = varNames{k};
            if evalin('base',sprintf('exist(''%s'',''var'')',v))
                oldVars.(v) = evalin('base',v);
            end
            assignin('base',v,tempVars.(v));
        end

        % Run the hidden fit
        hFig = figure('Visible','off');
        try
            run(fullfile(scriptDir,'SS_TRPLfit_IRFfit.m'));
            % Retrieve outputs if they exist
            if evalin('base','exist(''paramgs'',''var'')'),   paramgs   = evalin('base','paramgs');   end
            if evalin('base','exist(''fvalgs'',''var'')'),    fvalgs    = evalin('base','fvalgs');    end
            if evalin('base','exist(''ChiSq'',''var'')'),     ChiSq     = evalin('base','ChiSq');     end
            if evalin('base','exist(''RedChiSq'',''var'')'),  RedChiSq  = evalin('base','RedChiSq');  end
        catch ME
            close(hFig);
            % restore old base variables
            for k = 1:numel(varNames)
                v = varNames{k};
                if isfield(oldVars,v)
                    assignin('base',v,oldVars.(v));
                else
                    evalin('base',sprintf('clear %s',v));
                end
            end
            uialert(app.UIFigure, sprintf('Fitting error: %s', ME.message), 'Fit Error');
            app.FitButton.Enable = 'on';
            return
        end

        % Restore base variables
        for k = 1:numel(varNames)
            v = varNames{k};
            if isfield(oldVars,v)
                assignin('base',v,oldVars.(v));
            else
                evalin('base',sprintf('clear %s',v));
            end
        end

        % Copy fit & residual plots into your UIAxes
        ax  = findall(hFig,'Type','axes');
        pos = cell2mat(get(ax,'Position'));
        [~,ord] = sort(pos(:,2),'descend');
        mainAx = ax(ord(1));
        resAx  = ax(ord(2));

        cla(app.UIAxes);
        copyobj(allchild(mainAx),app.UIAxes);
        app.UIAxes.XLim    = mainAx.XLim;
        app.UIAxes.YLim    = mainAx.YLim;
        app.UIAxes.YScale  = 'log';
        app.UIAxes.XLabel.String = mainAx.XLabel.String;
        app.UIAxes.YLabel.String = mainAx.YLabel.String;
        if ~isempty(mainAx.Legend)
            legend(app.UIAxes, mainAx.Legend.String,'Box','off');
        end

        cla(app.UIAxes2);
        copyobj(allchild(resAx),app.UIAxes2);
        app.UIAxes2.XLim    = resAx.XLim;
        app.UIAxes2.YLim    = resAx.YLim;
        app.UIAxes2.YScale  = 'linear';
        app.UIAxes2.Title.String  = resAx.Title.String;
        app.UIAxes2.XLabel.String = resAx.XLabel.String;
        app.UIAxes2.YLabel.String = resAx.YLabel.String;

        close(hFig);

        % Save into app.FitResults
        res = struct( ...
            'RawTime',RawTime, ...
            'RawIRF',RawIRF, ...
            'RawData',RawData, ...
            'ConvPad',ConvPad, ...
            'WindowLB',WindowLB, ...
            'WindowUB',WindowUB, ...
            'WindowBaseline',WindowBaseline, ...
            'ModelName',FitModel, ...
            'NumExpComponents',NumExpComponents, ...
            'NumPowerComponents',NumPowerComponents, ...
            'NumSecondComponents',NumSecondComponents ...
        );
        if exist('paramgs','var'),   res.paramgs   = paramgs;   end
        if exist('fvalgs','var'),    res.fvalgs    = fvalgs;    end
        if exist('ChiSq','var'),     res.ChiSq     = ChiSq;     end
        if exist('RedChiSq','var'),  res.RedChiSq  = RedChiSq;  end
        app.FitResults = res;

        % Re-enable the Fit button
        app.FitButton.Enable = 'on';
 

        end

        % Callback function
        function ConvPadnsEditFieldValueChanged(app, event)
            app.ConvPadValue = app.ConvPadnsEditField.Value;


        end

        % Button pushed function: ExportDataasmatButton
        function ExportDataasmatButtonPushed(app, event)
            % Check if fit results are available before attempting export
if isempty(fieldnames(app.FitResults))
    uialert(app.UIFigure,'No fit results to export. Run Fit first.','Export Error');
    return
end

% Prompt user to choose a .mat file location for saving the results
[file, path] = uiputfile('*.mat', 'Save results as');
if isequal(file, 0)
    return;  % Exit if user cancels the file save dialog
end

% Save the fit results structure to the specified .mat file
filename = fullfile(path, file);
tmp = app.FitResults;
save(filename, '-struct', 'tmp');

% Inform the user that the export was successful
uialert(app.UIFigure, sprintf('Results saved to\n%s', filename), 'Export Success');


        end

        % Value changed function: WindowUpperBoundnsEditField
        function WindowUpperBoundnsEditFieldValueChanged(app, event)
            app.WindowUBValue = app.WindowUpperBoundnsEditField.Value;

        end

        % Value changed function: WindowLowerBoundnsEditField
        function WindowLowerBoundnsEditFieldValueChanged(app, event)
            app.WindowLBValue = app.WindowLowerBoundnsEditField.Value;


        end

        % Value changed function: WindowBaselineStartindexEditField
        function WindowBaselineStartindexEditFieldValueChanged(app, event)
            app.WindowBaselineStartValue = app.WindowBaselineStartindexEditField.Value;

        end

        % Value changed function: WindowBaselineEndindexEditField
        function WindowBaselineEndindexEditFieldValueChanged(app, event)
            app.WindowBaselineEndValue = app.WindowBaselineEndindexEditField.Value;

        end

        % Button pushed function: ExportTRPLDecayasFigButton
        function ExportTRPLDecayasFigButtonPushed(app, event)

           % Check that fitting results exist before proceeding with export
if isempty(fieldnames(app.FitResults))
    uialert(app.UIFigure,'No fit results to export. Run Fit first.','Export Error');
    return
end

% Prompt the user to select a file format for exporting the TRPL decay plot
if ispc
    formats = {'PDF','.fig','EMF'};
else
    formats = {'PDF','.fig','SVG'};
end
[fIdx, ok] = listdlg( ...
    'PromptString','Select TRPL-decay export format:', ...
    'ListString',formats, ...
    'SelectionMode','single' );
if ~ok, return, end

% Normalize format selection and get file extension
fmt = formats{fIdx};
ext = lower(strrep(fmt,'.',''));

% Prompt user to choose file name and location for saving
filter = {['*.' ext], [upper(ext) ' Files (*.' ext ')']};
[file, path] = uiputfile(filter, 'Save TRPL decay graph as');
if isequal(file,0), return, end

% Append extension to file name if not already present
[~, ~, fext] = fileparts(file);
if ~strcmpi(fext, ['.' ext])
    file = [file '.' ext];
end
fname = fullfile(path, file);

% Perform export based on selected format
switch ext
    case {'pdf','emf','svg'}
        % Export the plot using vector graphics
        exportgraphics(app.UIAxes, fname, 'ContentType','vector');

    case 'fig'
        % Export as .fig: copy axes to a temporary figure before saving
        tmpf = figure('Visible','off');
        axCopy = copyobj(app.UIAxes, tmpf);
        set(axCopy, 'Units','normalized', 'Position',[0.13 0.11 0.775 0.815]);

        % Copy legend if it exists
        if ~isempty(app.UIAxes.Legend)
            lg = legend(axCopy, app.UIAxes.Legend.String);
            lg.Location = app.UIAxes.Legend.Location;
        end

        % Save and close the temporary figure
        tmpf.Visible = 'on';
        saveas(tmpf, fname);
        close(tmpf);

    otherwise
        % Handle unexpected or unsupported format types
        uialert(app.UIFigure,'Unknown format selected.','Export Error');
        return
end

% Notify user that the export was completed successfully
uialert(app.UIFigure, sprintf('TRPL decay graph saved to\n%s', fname), 'Export Success');


        end

        % Button pushed function: ExportResidualsasFigButton
        function ExportResidualsasFigButtonPushed(app, event)
          % Check that fit results exist before attempting to export residuals
if isempty(fieldnames(app.FitResults))
    uialert(app.UIFigure,'No fit results to export. Run Fit first.','Export Error');
    return
end

% Prompt the user to select a file format for exporting the residuals plot
if ispc
    formats = {'PDF','.fig','EMF'};
else
    formats = {'PDF','.fig','SVG'};
end
[fIdx, ok] = listdlg( ...
    'PromptString','Select residuals-export format:', ...
    'ListString',formats, ...
    'SelectionMode','single' );
if ~ok, return, end

% Normalize and parse selected format
fmt = formats{fIdx};
ext = lower(strrep(fmt,'.',''));

% Prompt user for save location and filename
filter = {['*.' ext], [upper(ext) ' Files (*.' ext ')']};
[file, path] = uiputfile(filter, 'Save residuals graph as');
if isequal(file,0), return, end

% Ensure file has correct extension
[~, ~, fext] = fileparts(file);
if ~strcmpi(fext, ['.' ext])
    file = [file '.' ext];
end
fname = fullfile(path, file);

% Export residuals plot using selected format and app.UIAxes2
switch ext
    case {'pdf','emf','svg'}
        % Export as vector format directly from residuals axes
        exportgraphics(app.UIAxes2, fname, 'ContentType','vector');

    case 'fig'
        % Create a temporary figure and copy residuals axes into it
        tmpf = figure('Visible','off');
        axCopy = copyobj(app.UIAxes2, tmpf);
        set(axCopy, 'Units','normalized', 'Position',[0.13 0.11 0.775 0.815]);

        % Recreate legend if it exists
        if ~isempty(app.UIAxes2.Legend)
            lg = legend(axCopy, app.UIAxes2.Legend.String);
            lg.Location = app.UIAxes2.Legend.Location;
        end

        % Save and close the temporary figure
        tmpf.Visible = 'on';
        saveas(tmpf, fname);
        close(tmpf);

    otherwise
        % Show error for unsupported formats
        uialert(app.UIFigure,'Unknown format selected.','Export Error');
        return
end

% Notify user that export completed successfully
uialert(app.UIFigure, sprintf('Residuals graph saved to\n%s', fname), 'Export Success');

        end

        % Button pushed function: LoadDecayDataButton
        function LoadDecayDataButtonPushed(app, event)
           % Require IRF data to be loaded before allowing decay import
if isempty(app.RawTime)
    uialert(app.UIFigure, ...
        'Please load IRF data before loading decay data.', ...
        'Data Missing');
    return
end

% Prompt user to select a TRPL decay Excel file
[decay_file, decay_path] = uigetfile( ...
    {'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'}, ...
    'Select TRPL Decay Excel File');
if isequal(decay_file,0)
    uialert(app.UIFigure,'Decay file selection canceled.','File Error');
    return
end

try
    % Construct full path to the selected file
    fname = fullfile(decay_path, decay_file);

    % Read entire worksheet as raw cells to detect header structure
    rawCells = readcell(fname);

    % Search for the first row containing a colon (used to infer header row)
    hasColon = cellfun(@(x) ischar(x) && contains(x, ':'), rawCells);
    headerRow = find(any(hasColon,2), 1, 'first');

    % Read table using inferred or default header row
    if ~isempty(headerRow)
        tblData = readtable(fname, ...
            'PreserveVariableNames', true, ...
            'ReadVariableNames',    true, ...
            'HeaderLines',          headerRow-1);
    else
        tblData = readtable(fname);  % default: assume first row is header
    end

    % Prompt user to select columns for time and decay data (auto-select if only one column exists)
    columnNames = tblData.Properties.VariableNames;
    if numel(columnNames) == 1
        timeCol = 1;
        dataCol = 1;
    else
        [timeIdx, ok1] = listdlg( ...
            'PromptString','Select Time column:', ...
            'ListString',    columnNames, ...
            'SelectionMode', 'single', ...
            'ListSize',[300,200]);
        if ~ok1, return; end

        [dataIdx, ok2] = listdlg( ...
            'PromptString','Select Decay data column:', ...
            'ListString',    columnNames, ...
            'SelectionMode', 'single', ...
            'ListSize',[300,200]);
        if ~ok2, return; end

        timeCol = timeIdx;
        dataCol = dataIdx;
    end

    % Extract time and decay data from selected columns
    timeData = tblData{:,timeCol}(:)';
    rawData  = tblData{:,dataCol}(:)';

    % Interpolate decay data to match IRF time axis if needed
    if numel(timeData) ~= numel(app.RawTime) || any(timeData ~= app.RawTime)
        app.RawData = interp1(timeData, rawData, app.RawTime, 'pchip');
    else
        app.RawData = rawData;
    end

    % Plot IRF and decay data on a shared semilog Y-axis
    cla(app.UIAxes);
    hold(app.UIAxes,'on');
    semilogy(app.UIAxes, app.RawTime, app.RawIRF,  '-', 'DisplayName', 'IRF');
    semilogy(app.UIAxes, app.RawTime, app.RawData, '--', 'DisplayName', 'TRPL Decay');
    hold(app.UIAxes,'off');
    title(app.UIAxes, 'IRF and TRPL Decay');
    xlabel(app.UIAxes, 'Time (ns)');
    ylabel(app.UIAxes, 'Counts');
    legend(app.UIAxes, 'Location', 'best');
    app.UIAxes.YScale = 'log';

    % Reset stored click coordinates used for interactive range setting
    app.FitRangeClicks = [];

    % Confirm successful load to user
    uialert(app.UIFigure,'Decay data loaded and overlaid!','Success');
catch ME
    % Display error message if loading fails
    uialert(app.UIFigure, ME.message, 'Load Error');
end

        end

        % Callback function
        function ExportResidualsMACONLYButtonPushed(app, event)

        end

        % Callback function
        function ExportTRPLDecayMACONLYButtonPushed(app, event)

        end

        % Callback function
        function OneFileButtonPushed(app, event)
            %  Make sure IRF is loaded first
            if isempty(app.RawTime)
                uialert(app.UIFigure, 'Please load IRF data first.', 'Error');
                return
            end

            %  Prompt for Excel file
            [file, path] = uigetfile({'*.xlsx;*.xls','Excel Files (*.xlsx, *.xls)'}, ...
                'Select Excel File with TRPL Traces');
            if isequal(file,0)
                return  % user canceled
            end
            fname = fullfile(path, file);

            %  Get sheet names
            [~, sheetNames] = xlsfinfo(fname);
            if isempty(sheetNames)
                uialert(app.UIFigure, 'No sheets found in file.', 'Read Error');
                return
            end
            if numel(sheetNames)>1
                [sIdx, ok] = listdlg( ...
                    'PromptString','Select a sheet:', ...
                    'ListString',sheetNames, ...
                    'SelectionMode','single' );
                if ~ok, return, end
                sheet = sheetNames{sIdx};
            else
                sheet = sheetNames{1};
            end

            %  Read the chosen sheet
            T = readtable(fname, 'Sheet', sheet, 'PreserveVariableNames', true);

            % Pick which column is the decay trace
            vars = T.Properties.VariableNames;
            if numel(vars)>2
                [vIdx, ok2] = listdlg( ...
                    'PromptString','Select TRPL decay column:', ...
                    'ListString',vars(2:end), ...
                    'SelectionMode','single' );
                if ~ok2, return, end
                dataVar = vars{vIdx+1};
            else
                dataVar = vars{2};
            end

            %  Extract and (if needed) interpolate
            timeData = T{:,1}(:)';
            rawData  = T{:,dataVar}(:)';
            if numel(timeData)~=numel(app.RawTime) || any(timeData~=app.RawTime)
                app.RawData = interp1(timeData, rawData, app.RawTime, 'pchip');
            else
                app.RawData = rawData;
            end

            %  Plot
            cla(app.UIAxes2);
            plot(app.UIAxes2, app.RawTime, app.RawData);
            title(app.UIAxes2, 'TRPL Decay');
            xlabel(app.UIAxes2, 'Time (ns)');
            ylabel(app.UIAxes2, 'Counts');

            uialert(app.UIFigure, 'TRPL data loaded successfully!', 'Success');
        end

        % Button pushed function: BuildModelButton
        function BuildModelButtonPushed(app, event)
            % Ask user how many of each component
            prompt = { ...
                '# Exponential components:', ...
                '# Power-law components:', ...
                '# Second-order components:' ...
                };
            dlgTitle = 'Build Fit Model';
            dims = [1 40];
            def = {'1','0','0'};
            answer = inputdlg(prompt, dlgTitle, dims, def);
            if isempty(answer), return; end

            nExp = max(0, round(str2double(answer{1})));
            nPow = max(0, round(str2double(answer{2})));
            nSec = max(0, round(str2double(answer{3})));

            % Validate that at least one component is specified
            if nExp + nPow + nSec == 0
                uialert(app.UIFigure, 'At least one component must be specified.', 'Model Error');
                return;
            end

            % Store component counts
            app.NumExpComponents = nExp;
            app.NumPowerComponents = nPow;
            app.NumSecondComponents = nSec;

            % Generate model name
            modelParts = {};
            if nExp > 0, modelParts{end+1} = sprintf('%dexp', nExp); end
            if nPow > 0, modelParts{end+1} = sprintf('%dpower', nPow); end
            if nSec > 0, modelParts{end+1} = sprintf('%dsecond', nSec); end
            app.ModelName = strjoin(modelParts, '');

            % Build parameter table
            app.buildParameterTable();

            uialert(app.UIFigure, sprintf('Model "%s" built successfully!', app.ModelName), 'Build Success');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)


        end

        % Button pushed function: ResetClearButton
        function ResetClearButtonPushed(app, event)
                  % --- Clear all internal data & state ---
        app.RawTime                   = [];
        app.RawIRF                    = [];
        app.RawData                   = [];
        app.FitResults                = struct();
        app.FitType                   = [];
        app.FitOptions                = [];
        app.FitModel                  = '';
        app.FitRangeClicks            = [];
        app.BaselineRangeClicks       = [];
        app.NumExpComponents          = 0;
        app.NumPowerComponents        = 0;
        app.NumSecondComponents       = 0;
        app.ModelName                 = '';
        app.ParameterTable            = {};
        app.IRFTimeShift              = 0;
        app.FitRangeMarkers           = matlab.graphics.chart.primitive.Line.empty;
        app.BaselineRangeMarkers      = matlab.graphics.chart.primitive.Line.empty;
        app.FitRangePatch             = matlab.graphics.primitive.Patch.empty;
        app.BaselineRangePatch        = matlab.graphics.primitive.Patch.empty;

        % Remove any lingering patches, markers, draggable lines
        delete(findall(app.UIAxes,'-regexp','Tag','window|baseline'));

        % --- Fully reset both axes ---
        cla(app.UIAxes,  'reset');
        cla(app.UIAxes2, 'reset');

        % Restore axes labels, title, and scale to first‐launch defaults
        title(app.UIAxes, '');
        xlabel(app.UIAxes, 'Time (ns)');
        ylabel(app.UIAxes, 'Counts');
        app.UIAxes.XScale = 'linear';
        app.UIAxes.YScale = 'linear';

        title(app.UIAxes2, '');
        xlabel(app.UIAxes2, 'Time (ns)');
        ylabel(app.UIAxes2, 'Residuals');
        app.UIAxes2.XScale = 'linear';
        app.UIAxes2.YScale = 'linear';

        % Re-enable click callback on the main axes
        app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);

        % --- Reset UI controls to their defaults ---
        app.ConvPadValue                              = 15;
        app.ConvPadnsEditField.Value                  = 15;
        app.WindowLBValue                             = 48;
        app.WindowLowerBoundnsEditField.Value         = 48;
        app.WindowUBValue                             = 80;
        app.WindowUpperBoundnsEditField.Value         = 80;
        app.WindowBaselineStartValue                  = 900;
        app.WindowBaselineStartindexEditField.Value   = 900;
        app.WindowBaselineEndValue                    = 1100;
        app.WindowBaselineEndindexEditField.Value     = 1100;

        app.SetWindowBoundsButton.Value               = false;
        app.SetWindowBoundsButton.Text                = 'Set Window Bounds';
        app.SetWindowBoundsButton.BackgroundColor     = [1 1 1];

        app.SetBaselineBoundsButton.Value             = false;
        app.SetBaselineBoundsButton.Text              = 'Set Baseline Bounds';
        app.SetBaselineBoundsButton.BackgroundColor   = [1 1 1];

        % Reset the parameter table structure
        app.UITable.Data       = {};
        app.UITable.ColumnName = { ...
            'Paramater'; ...
            'Initial Guess'; ...
            'Lower Bound'; ...
            'Uppder Bound'  ...
        };

        % Notify user
        uialert(app.UIFigure, 'All data and settings have been reset.', 'Reset Complete');


        end

        % Value changed function: SetWindowBoundsButton
        function SetWindowBoundsButtonValueChanged(app, event)
          if app.SetWindowBoundsButton.Value
            % Remove any old fit-lines
            delete(findobj(app.UIAxes,'Tag','windowLineLow'));
            delete(findobj(app.UIAxes,'Tag','windowLineHigh'));

            % Compute mid-segment of the current X-axis
            xl    = app.UIAxes.XLim;
            width = diff(xl);
            newLB = xl(1) + 0.25*width;  % 25% in from left
            newUB = xl(1) + 0.75*width;  % 25% in from right

            % Update the numeric fields + stored values
            app.WindowLowerBoundnsEditField.Value = newLB;
            app.WindowUpperBoundnsEditField.Value = newUB;
            app.WindowLBValue = newLB;
            app.WindowUBValue = newUB;

            % Draw draggable lines at those positions
            yl = app.UIAxes.YLim;
            lLow = drawline(app.UIAxes, ...
                'Position',[newLB yl(1); newLB yl(2)], ...
                'InteractionsAllowed','translate', ...
                'Color',[0.3,0.3,0.7], ...
                'Tag','windowLineLow');
            lHigh = drawline(app.UIAxes, ...
                'Position',[newUB yl(1); newUB yl(2)], ...
                'InteractionsAllowed','translate', ...
                'Color',[0.3,0.3,0.7], ...
                'Tag','windowLineHigh');

            % Live-update as the user drags
            addlistener(lLow,  'MovingROI', @(s,e) updateWindowLB(app,s,e));
            addlistener(lHigh, 'MovingROI', @(s,e) updateWindowUB(app,s,e));

            % Toggle appearance
            app.SetWindowBoundsButton.Text            = 'Adjust Fit Bounds';
            app.SetWindowBoundsButton.BackgroundColor  = [0.8 1 0.8];
        else
            % Turn off: remove lines & restore button style
            delete(findobj(app.UIAxes,'Tag','windowLineLow'));
            delete(findobj(app.UIAxes,'Tag','windowLineHigh'));
            app.SetWindowBoundsButton.Text           = 'Set Window Bounds';
            app.SetWindowBoundsButton.BackgroundColor = [1 1 1];
        end
        end

        % Value changed function: SetBaselineBoundsButton
        function SetBaselineBoundsButtonValueChanged(app, event)
if app.SetBaselineBoundsButton.Value
        % --- Guard: require data loaded ---
        if isempty(app.RawTime) || isempty(app.RawData)
            uialert(app.UIFigure, ...
                'Please load IRF and decay data before setting baseline bounds.', ...
                'Data Missing');
            % turn the toggle back off
            app.SetBaselineBoundsButton.Value           = false;
            return
        end

        % Remove any old baseline lines
        delete(findobj(app.UIAxes,'Tag','baselineLineLow'));
        delete(findobj(app.UIAxes,'Tag','baselineLineHigh'));

        % Compute 25%/75% of the current X-axis limits
        xl    = app.UIAxes.XLim;
        width = diff(xl);
        newStartX = xl(1) + 0.25*width;
        newEndX   = xl(1) + 0.75*width;

        % Convert those positions back to data indices
        [~, startIdx] = min(abs(app.RawTime - newStartX));
        [~, endIdx]   = min(abs(app.RawTime - newEndX));

        % Update numeric fields & stored values
        app.WindowBaselineStartindexEditField.Value = startIdx;
        app.WindowBaselineEndindexEditField.Value   = endIdx;
        app.WindowBaselineStartValue = startIdx;
        app.WindowBaselineEndValue   = endIdx;

        % Draw the draggable lines at those X positions
        yl = app.UIAxes.YLim;
        lLow = drawline(app.UIAxes, ...
            'Position',[newStartX yl(1); newStartX yl(2)], ...
            'InteractionsAllowed','translate', ...
            'Color',[0.7,0.3,0.3], ...
            'Tag','baselineLineLow');
        lHigh = drawline(app.UIAxes, ...
            'Position',[newEndX   yl(1); newEndX   yl(2)], ...
            'InteractionsAllowed','translate', ...
            'Color',[0.7,0.3,0.3], ...
            'Tag','baselineLineHigh');

        % Live-update as the user drags
        addlistener(lLow,  'MovingROI', @(s,e) updateBaselineStart(app,s,e));
        addlistener(lHigh, 'MovingROI', @(s,e) updateBaselineEnd(app,s,e));

        % Toggle appearance
        app.SetBaselineBoundsButton.Text           = 'Adjust Baseline';
        app.SetBaselineBoundsButton.BackgroundColor = [1 0.8 0.8];

    else
        % Turn off: remove lines & restore button style
        delete(findobj(app.UIAxes,'Tag','baselineLineLow'));
        delete(findobj(app.UIAxes,'Tag','baselineLineHigh'));
        app.SetBaselineBoundsButton.Text           = 'Set Baseline Bounds';
        app.SetBaselineBoundsButton.BackgroundColor = [1 1 1];
    end

        end

        % Value changed function: ConvPadnsEditField
        function ConvPadnsEditFieldValueChanged2(app, event)
            value = app.ConvPadnsEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            xlabel(app.UIAxes, 'Time (ns)')
            ylabel(app.UIAxes, 'Counts')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.GridLayout);
            xlabel(app.UIAxes2, 'Time (ns)')
            ylabel(app.UIAxes2, 'Residuals')
            zlabel(app.UIAxes2, 'Z')
            app.LoadIRFDataButton = uibutton(app.GridLayout, 'push');
            app.LoadIRFDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadIRFDataButtonPushed, true);
            app.LoadIRFDataButton.Text = 'Load IRF Data';
            app.ResetClearButton = uibutton(app.GridLayout, 'push');
            app.ResetClearButton.ButtonPushedFcn = createCallbackFcn(app, @ResetClearButtonPushed, true);
            app.ResetClearButton.Text = 'Reset/Clear ';
            app.LoadDecayDataButton = uibutton(app.GridLayout, 'push');
            app.LoadDecayDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDecayDataButtonPushed, true);
            app.LoadDecayDataButton.Text = 'Load Decay Data';
            app.SetWindowBoundsButton = uibutton(app.GridLayout, 'state');
            app.SetWindowBoundsButton.ValueChangedFcn = createCallbackFcn(app, @SetWindowBoundsButtonValueChanged, true);
            app.SetWindowBoundsButton.Text = 'Set Window Bounds';
            app.SetBaselineBoundsButton = uibutton(app.GridLayout, 'state');
            app.SetBaselineBoundsButton.ValueChangedFcn = createCallbackFcn(app, @SetBaselineBoundsButtonValueChanged, true);
            app.SetBaselineBoundsButton.Text = 'Set Baseline Bounds';
            app.ExportResidualsasFigButton = uibutton(app.GridLayout, 'push');
            app.ExportResidualsasFigButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResidualsasFigButtonPushed, true);
            app.ExportResidualsasFigButton.Text = 'Export Residuals as Fig';
            app.ExportTRPLDecayasFigButton = uibutton(app.GridLayout, 'push');
            app.ExportTRPLDecayasFigButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTRPLDecayasFigButtonPushed, true);
            app.ExportTRPLDecayasFigButton.Text = 'Export TRPL Decay as Fig ';
            app.ExportDataasmatButton = uibutton(app.GridLayout, 'push');
            app.ExportDataasmatButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataasmatButtonPushed, true);
            app.ExportDataasmatButton.Text = 'Export Data as .mat';
            app.FitButton = uibutton(app.GridLayout, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit ';
            app.BuildModelButton = uibutton(app.GridLayout, 'push');
            app.BuildModelButton.ButtonPushedFcn = createCallbackFcn(app, @BuildModelButtonPushed, true);
            app.BuildModelButton.Text = 'Build Model';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Paramater'; 'Initial Guess'; 'Lower Bound'; 'Uppder Bound'};
            app.UITable.RowName = {};
            app.WindowLowerBoundnsEditFieldLabel = uilabel(app.GridLayout);
            app.WindowLowerBoundnsEditFieldLabel.Text = 'Window Lower Bound (ns)';
            app.WindowLowerBoundnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowLowerBoundnsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowLowerBoundnsEditFieldValueChanged, true);
            app.WindowLowerBoundnsEditField.Value = 48;
            app.WindowUpperBoundnsEditFieldLabel = uilabel(app.GridLayout);
            app.WindowUpperBoundnsEditFieldLabel.Text = {'Window Upper Bound (ns)'; ''};
            app.WindowUpperBoundnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowUpperBoundnsEditField.ValueChangedFcn = createCallbackFcn(app, @WindowUpperBoundnsEditFieldValueChanged, true);
            app.WindowUpperBoundnsEditField.Value = 80;
            app.WindowBaselineStartindexEditFieldLabel = uilabel(app.GridLayout);
            app.WindowBaselineStartindexEditFieldLabel.Text = 'Window Baseline Start (index)';
            app.WindowBaselineStartindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowBaselineStartindexEditField.ValueChangedFcn = createCallbackFcn(app, @WindowBaselineStartindexEditFieldValueChanged, true);
            app.WindowBaselineStartindexEditField.Value = 900;
            app.WindowBaselineEndindexEditFieldLabel = uilabel(app.GridLayout);
            app.WindowBaselineEndindexEditFieldLabel.Text = 'Window Baseline End (index)';
            app.WindowBaselineEndindexEditField = uieditfield(app.GridLayout, 'numeric');
            app.WindowBaselineEndindexEditField.ValueChangedFcn = createCallbackFcn(app, @WindowBaselineEndindexEditFieldValueChanged, true);
            app.WindowBaselineEndindexEditField.Value = 1100;
            app.ConvPadnsEditFieldLabel = uilabel(app.GridLayout);
            app.ConvPadnsEditFieldLabel.Text = 'Conv Pad (ns)';
            app.ConvPadnsEditField = uieditfield(app.GridLayout, 'numeric');
            app.ConvPadnsEditField.ValueChangedFcn = createCallbackFcn(app, @ConvPadnsEditFieldValueChanged2, true);
            app.ConvPadnsEditField.Value = 15;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TRPL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end